classdef appMedicao < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TensaoBot              matlab.ui.control.Button
        CorrenteBot            matlab.ui.control.Button
        TempTPBot              matlab.ui.control.Button
        IlumiBot               matlab.ui.control.Button
        Grafico                matlab.ui.control.UIAxes
        PotenciaBot            matlab.ui.control.Button
        Energia                matlab.ui.control.Button
        LabelNumericEditField  matlab.ui.control.Label
        TempLM                 matlab.ui.control.NumericEditField
        Label                  matlab.ui.control.Label
    end

    properties (Access = public)
        flag; % 1 = tensao, 2 = corrente, 3 = tempLM, 4 = tempTP, 5 = ilumin, 6 = potencia, 7 = energia
        tensao;
        tamTensao;
        corrente;
        tamCorrente;
        tempTP;
        tamTempTP; 
        ilumin;
        tamIlumin;
        potencia;
        tamPotencia;
        energia;
        tamEnergia;
        
        tempLM;
    end

    methods (Access = public)  
        function TensaoRefresh(app)
            plot(app.Grafico,[1:app.tamTensao],app.tensao,'-o');
        end

        function CorrenteRefresh(app)
            plot(app.Grafico,[1:app.tamCorrente],app.corrente,'-o');
        end
            
        function TempTPRefresh(app)
            plot(app.Grafico,[1:app.tamTempTP],app.tempTP,'-o');
        end
       
        function IluminRefresh(app)
            plot(app.Grafico,[1:app.tamIlumin],app.ilumin,'-o');
        end
        
        function PotenciaRefresh(app)
            plot(app.Grafico,[1:app.tamPotencia],app.potencia,'-o');
        end
        
        function EnergiaRefresh(app)
            plot(app.Grafico,[2:2:2*app.tamEnergia],app.energia,'-o');
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.flag = 0;
            app.tempLM = app.TempLM.Value;
        end

        % Button pushed function: TempTPBot
        function TempTPBotButtonPushed(app, event)
            app.flag = 3;
            plot(app.Grafico, [1:app.tamTempTP],app.tempTP,'-o');
            title(app.Grafico,'Medição de temperatura utilizando Termopar');
            ylabel(app.Grafico,'Temperatura (°C)');
            xlabel(app.Grafico,'Amostras');
        end

        % Button pushed function: IlumiBot
        function IlumiBotButtonPushed(app, event)
            app.flag = 4;
            plot(app.Grafico, [1:app.tamIlumin],app.ilumin,'-o');
            title(app.Grafico,'Medição de iluminância');
            ylabel(app.Grafico,'Iluminância (lx)');
            xlabel(app.Grafico,'Amostras');
        end

        % Button pushed function: PotenciaBot
        function PotenciaBotButtonPushed(app, event)
            app.flag = 5;
            plot(app.Grafico, [1:app.tamPotencia],app.potencia,'-o');
            title(app.Grafico,'Potência');
            ylabel(app.Grafico,'Potência (W)');
            xlabel(app.Grafico,'Amostras');
        end

        % Button pushed function: TensaoBot
        function TensaoBotButtonPushed(app, event)
            app.flag = 1;
            plot(app.Grafico, [1:app.tamTensao],app.tensao,'-o');
            title(app.Grafico,'Medição de tensão');
            ylabel(app.Grafico,'Tensão (V)');
            xlabel(app.Grafico,'Amostras');
        end

        % Button pushed function: CorrenteBot
        function CorrenteBotButtonPushed(app, event)
            app.flag = 2;
            plot(app.Grafico, [1:app.tamCorrente],app.corrente,'-o');
            title(app.Grafico,'Medição de corrente');
            ylabel(app.Grafico,'Corrente (mA)');
            xlabel(app.Grafico,'Amostras');
        end

        % Button pushed function: Energia
        function EnergiaButtonPushed(app, event)
            app.flag = 6;
            plot(app.Grafico, [2:2:2*app.tamEnergia],app.energia,'-o');
            title(app.Grafico,'Energia consumida');
            ylabel(app.Grafico,'Energia (J)');
            xlabel(app.Grafico,'Tempo (s)');
        end

        % Value changed function: TempLM
        function TempLMValueChanged(app, event)
            app.tempLM = app.TempLM.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TensaoBot = uibutton(app.UIFigure, 'push');
            app.TensaoBot.ButtonPushedFcn = createCallbackFcn(app, @TensaoBotButtonPushed, true);
            app.TensaoBot.Text = 'Tensão';
            app.CorrenteBot = uibutton(app.UIFigure, 'push');
            app.CorrenteBot.ButtonPushedFcn = createCallbackFcn(app, @CorrenteBotButtonPushed, true);
            app.CorrenteBot.Text = 'Corrente';
            app.TempTPBot = uibutton(app.UIFigure, 'push');
            app.TempTPBot.ButtonPushedFcn = createCallbackFcn(app, @TempTPBotButtonPushed, true);
            app.TempTPBot.Text = 'Temperatura (TP)';
            app.IlumiBot = uibutton(app.UIFigure, 'push');
            app.IlumiBot.ButtonPushedFcn = createCallbackFcn(app, @IlumiBotButtonPushed, true);
            app.IlumiBot.Text = 'Ilmuninância';
            app.Grafico = uiaxes(app.UIFigure);
            app.PotenciaBot = uibutton(app.UIFigure, 'push');
            app.PotenciaBot.ButtonPushedFcn = createCallbackFcn(app, @PotenciaBotButtonPushed, true);
            app.PotenciaBot.Text = 'Potência';
            app.Energia = uibutton(app.UIFigure, 'push');
            app.Energia.ButtonPushedFcn = createCallbackFcn(app, @EnergiaButtonPushed, true);
            app.Energia.Text = 'Energia';
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'Temp LM35';
            app.TempLM = uieditfield(app.UIFigure, 'numeric');
            app.TempLM.Limits = [0 100];
            app.TempLM.ValueChangedFcn = createCallbackFcn(app, @TempLMValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'MarcioRASF';
        end
    end

    methods (Access = public)

        % Construct app
        function app = appMedicao

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end